classdef PID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SerialConfigurationPanel    matlab.ui.container.Panel
        PortsDropDownLabel          matlab.ui.control.Label
        PortsDropDown               matlab.ui.control.DropDown
        BaudRateDropDownLabel       matlab.ui.control.Label
        BaudRateDropDown            matlab.ui.control.DropDown
        ConnectButton               matlab.ui.control.StateButton
        Lamp                        matlab.ui.control.Lamp
        ResetGraphButton            matlab.ui.control.Button
        PidTunePanel                matlab.ui.container.Panel
        KpEditFieldLabel            matlab.ui.control.Label
        KpEditField                 matlab.ui.control.NumericEditField
        KiEditFieldLabel            matlab.ui.control.Label
        KiEditField                 matlab.ui.control.NumericEditField
        KdEditFieldLabel            matlab.ui.control.Label
        KdEditField                 matlab.ui.control.NumericEditField
        SetParametersButton         matlab.ui.control.Button
        SpeedEditFieldLabel         matlab.ui.control.Label
        SpeedEditField              matlab.ui.control.NumericEditField
        MotorspeedEditField_2Label  matlab.ui.control.Label
        MotorspeedEditField         matlab.ui.control.NumericEditField
        GetParametersButton         matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        PIDLabel                    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Com;
        val = 0;
        Stop = false;
        hP;
        hI;
        command;
        input = 0;
    end
    
    methods (Access = public)
        
        % Open serial communacation with arduino
        function open(app)
            app.Stop = false;
            if(strcmp(app.Com.Status,'closed'))
                fopen(app.Com);
            else
                fclose(app.Com);
                pause(1);
                fopen(app.Com);
            end
        end
        
        % Close serial communacation
        function close(app)
            app.Stop = true;
            pause(1);
            if(strcmp(app.Com,'open'))
                fclose(app.Com);
            end
        end
        
        function startCom(app)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            app.Com = serial(app.PortsDropDown.Value,'BaudRate',str2double(app.BaudRateDropDown.Value));
            set(app.Com,'TimeOut',5);
            set(app.Com,'Parity','None');
            set(app.Com,'FlowControl','None');
            set(app.Com,'Terminator','CR/LF');
            set(app.Com,'ReadAsyncMode','continuous');
        end
        
        %if there are not serial comport name in port combobox, then add the comport
        function Add_DropDown_Items(app, new)
            if ~any(ismember(app.PortsDropDown.Items, new))
                app.PortsDropDown.Items = [app.PortsDropDown.Items new];
            end
            app.PortsDropDown.Value = new(1);
        end
        
        
        function sendData(app)
            switch(app.command)
                case 0
                    fwrite(app.Com,[2 0],'uint8');
                case 1
                    Kp = typecast(single(app.KpEditField.Value),'uint8');
                    Ki = typecast(single(app.KiEditField.Value),'uint8');
                    Kd = typecast(single(app.KdEditField.Value),'uint8');
                    Ks = typecast(single(app.SpeedEditField.Value),'uint8');
                    fwrite(app.Com,[18,1,Kp,Ki,Kd,Ks],'uint8');
                    app.input = app.SpeedEditField.Value;
                    app.command = 0;
                case 2
                    fwrite(app.Com,[2 2],'uint8');
                    app.command = 0;
                otherwise
            end
        end
        
        
        function data = readData(app)
            data = fscanf(app.Com,'%f');
        end
    
        function set_Parameters(app,data)
            app.KpEditField.Value         = data(1);
            app.KiEditField.Value         = data(2);
            app.KdEditField.Value         = data(3);
            app.MotorspeedEditField.Value = data(4);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Ports = seriallist;
            col = size(Ports,2);
            if ~isempty(Ports) 
                for i=1:col
                    app.Add_DropDown_Items(Ports);
                end
            end
            
             % Animated for sensor data
            app.hP = animatedline(app.UIAxes,0,0,'Color','r','LineWidth',1);
            app.hI = animatedline(app.UIAxes,0,0,'Color','b','LineWidth',1);
            legend(app.UIAxes,"Output","Input");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {501, 501};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {230, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if(value == 1)
                app.startCom();
                app.ConnectButton.Text = "Disconnect";
                app.Lamp.Color = 'g';
                
                app.open();
                pause(2);
                i = 0;
                
                app.command = 0;
                while(~app.Stop)
%                     try
                        app.sendData();
                        timeOut = 0;
                        pause(0.005);
                        
                        byte = app.Com.BytesAvailable;
                        while(byte == 0 && timeOut ~= 100)
                            byte = app.Com.BytesAvailable;
                            timeOut = timeOut + 1;
                        end
    
                        data = app.readData();
                        
                        if(length(data) == 2)
                            addpoints(app.hP,i,data(1));
                            addpoints(app.hI,i,data(2));
                            app.MotorspeedEditField.Value = data(1);
                            app.SpeedEditField.Value      = data(2);
                            drawnow;
                            i = i +1;
                        else
                        app.set_Parameters(data);
                        end
                    
%                     catch
%                         disp("Error");
%                     end
                end
            else
                app.ConnectButton.Text = "Connect";
                app.Lamp.Color = 'r';
                app.close();
            end
                    
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            app.command = 1;
        end

        % Button pushed function: ResetGraphButton
        function ResetGraphButtonPushed(app, event)
            app.UIAxes.cla;
             % Animated for sensor data
            app.hP = animatedline(app.UIAxes,'Color','r','LineWidth',1);
            app.hI = animatedline(app.UIAxes,'Color','b','LineWidth',1);
            legend(app.UIAxes,"Output","Input");
        end

        % Button pushed function: GetParametersButton
        function GetParametersButtonPushed(app, event)
            app.command = 2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SerialConfigurationPanel = uipanel(app.LeftPanel);
            app.SerialConfigurationPanel.Title = 'Serial Configuration';
            app.PortsDropDownLabel = uilabel(app.SerialConfigurationPanel);
            app.PortsDropDownLabel.Text = 'Ports:';
            app.PortsDropDown = uidropdown(app.SerialConfigurationPanel);
            app.PortsDropDown.Items = {'COM3', 'COM4', 'COM5', 'COM6'};
            app.PortsDropDown.Value = 'COM3';
            app.BaudRateDropDownLabel = uilabel(app.SerialConfigurationPanel);
            app.BaudRateDropDownLabel.Text = 'BaudRate:';
            app.BaudRateDropDown = uidropdown(app.SerialConfigurationPanel);
            app.BaudRateDropDown.Items = {'9600', '19200', '38400', '57600', '115200', '250000'};
            app.BaudRateDropDown.Value = '115200';
            app.ConnectButton = uibutton(app.SerialConfigurationPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.SerialConfigurationPanel);
            app.ResetGraphButton = uibutton(app.SerialConfigurationPanel, 'push');
            app.ResetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraphButtonPushed, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            app.PidTunePanel = uipanel(app.LeftPanel);
            app.PidTunePanel.Title = 'Pid Tune';
            app.KpEditFieldLabel = uilabel(app.PidTunePanel);
            app.KpEditFieldLabel.Text = 'Kp:';
            app.KpEditField = uieditfield(app.PidTunePanel, 'numeric');
            app.KiEditFieldLabel = uilabel(app.PidTunePanel);
            app.KiEditFieldLabel.Text = 'Ki:';
            app.KiEditField = uieditfield(app.PidTunePanel, 'numeric');
            app.KdEditFieldLabel = uilabel(app.PidTunePanel);
            app.KdEditFieldLabel.Text = 'Kd:';
            app.KdEditField = uieditfield(app.PidTunePanel, 'numeric');
            app.SetParametersButton = uibutton(app.PidTunePanel, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.SpeedEditFieldLabel = uilabel(app.PidTunePanel);
            app.SpeedEditFieldLabel.Text = 'Speed:';
            app.SpeedEditField = uieditfield(app.PidTunePanel, 'numeric');
            app.MotorspeedEditField_2Label = uilabel(app.LeftPanel);
            app.MotorspeedEditField_2Label.Text = 'Motor speed:';
            app.MotorspeedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GetParametersButton = uibutton(app.LeftPanel, 'push');
            app.GetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @GetParametersButtonPushed, true);
            app.GetParametersButton.Text = 'Get Parameters';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PIDLabel = uilabel(app.RightPanel);
            app.PIDLabel.Text = 'PID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end